classdef App_Principal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        P_inicio_1                     matlab.ui.container.Panel
        B_Tendencias_1                 matlab.ui.control.Button
        B_Ingresar_Electrodomestcos_1  matlab.ui.control.Button
        B_Ingresar_Recibos_1           matlab.ui.control.Button
        CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        Container_Botones              matlab.ui.container.GridLayout
        B_EnergiaProducida_3           matlab.ui.control.Button
        B_Instalacion_2                matlab.ui.control.Button
        B_CalculadoraConsumo_1         matlab.ui.control.Button
        P_Graficos_Tendencia           matlab.ui.container.Panel
        G_Graficos_Tendencia           matlab.ui.container.GridLayout
        P_Tendencias_1                 matlab.ui.container.Panel
        P_Tendencias_Vinculos          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        B_EditarE_Tendencia_1          matlab.ui.control.Button
        B_EditarRec_Tendencias_1       matlab.ui.control.Button
        B_AnadirRec_Tendencias_1       matlab.ui.control.Button
        B_AnadirE_Tendencias_1         matlab.ui.control.Button
        P_TendenciaCostoMeses_1        matlab.ui.container.Panel
        TA_DescripcionAnalisisCostoMeses_Tendencias_1  matlab.ui.control.TextArea
        G_CostoMeses_Tendencias_1      matlab.ui.control.UIAxes
        P_TendenciaAnalisisRadTemp_1   matlab.ui.container.Panel
        B_Tendencia_CambiarGraf_1      matlab.ui.control.StateButton
        TA_DescripcionAnalisisRadTemp_Tendencias_1  matlab.ui.control.TextArea
        G_RadTemp_Tendencias_2         matlab.ui.control.UIAxes
        G_RadTemp_Tendencias_1         matlab.ui.control.UIAxes
        P_TendenciaProduccion_1        matlab.ui.container.Panel
        TA_DescripcionAnalisisPvsT_Tendencias_1  matlab.ui.control.TextArea
        G_PvsT_Tendencias_1            matlab.ui.control.UIAxes
        AnlisisdeDatosLabel            matlab.ui.control.Label
        P_Botones_Tendencia            matlab.ui.container.Panel
        G_Botones_Tendencia            matlab.ui.container.GridLayout
        B_TendenciasConsumo_1          matlab.ui.control.Button
        B_DatosClimaticos_1            matlab.ui.control.Button
        B_TendenciasCostoKWH_1         matlab.ui.control.Button
    end

    
    properties (Access = public)
                Usuario
                M1
                M2
                M3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Usuario = C_Usuario(1, getenv('USERNAME'), "Arequipa");
            app.M1 = M1_Calculadora_Consumo(app.Usuario);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Carga_BD(app);
            delete(app)
        end

        % Button pushed function: B_TendenciasCostoKWH_1
        function B_Tendencias_Consumo_View(app, event)
            FC_showView(app,"1.3.2")
            GraficoConsumoTendencia(app)
        end

        % Button pushed function: B_TendenciasConsumo_1
        function B_Tendencias_DatosClimaticos_View(app, event)
            FC_showView(app,"1.3.4")
            plotRadiacionTemperatura(app)
        end

        % Button pushed function: B_DatosClimaticos_1
        function B_Tendencias_CostoKWH_View(app, event)
            FC_showView(app,"1.3.3")
            GraficoCostoKWH(app)
        end

        % Button pushed function: B_EditarRec_Tendencias_1
        function B_Tendencias_ModificarRec_View(app, event)
            FC_showView(app,"1.1.4")
        end

        % Button pushed function: B_AnadirRec_Tendencias_1
        function B_Tendencias_AnadirRec_View(app, event)
            FC_showView(app,"1.1.3")
        end

        % Button pushed function: B_EditarE_Tendencia_1
        function B_Tendencias_ModificarE_View(app, event)
            FC_showView(app,"1.2.5")
        end

        % Button pushed function: B_AnadirE_Tendencias_1
        function B_Tendencias_AnadirE_View(app, event)
            FC_showView(app,"1.2.1")
        end

        % Button pushed function: B_Tendencias_1
        function B_Tendencias_View(app, event)
            FC_showView(app,"1.3.1")
        end

        % Button pushed function: B_CalculadoraConsumo_1
        function CalculadoraConsumoView_1(app, event)
            FC_showView(app,"1")
        end

        % Value changed function: B_Tendencia_CambiarGraf_1
        function B_Tendencia_CambiarGraf_1ValueChanged(app, event)
            value = app.B_Tendencia_CambiarGraf_1.Value;
            switchGraphVisibility(app, app.G_RadTemp_Tendencias_1, app.G_RadTemp_Tendencias_2, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.P_Botones_Tendencia = uipanel(app.UIFigure);
            app.G_Botones_Tendencia = uigridlayout(app.P_Botones_Tendencia);
            app.B_TendenciasCostoKWH_1 = uibutton(app.G_Botones_Tendencia, 'push');
            app.B_TendenciasCostoKWH_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_Consumo_View, true);
            app.B_TendenciasCostoKWH_1.Text = 'Consumo';
            app.B_DatosClimaticos_1 = uibutton(app.G_Botones_Tendencia, 'push');
            app.B_DatosClimaticos_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_CostoKWH_View, true);
            app.B_DatosClimaticos_1.Text = 'Costo KWH';
            app.B_TendenciasConsumo_1 = uibutton(app.G_Botones_Tendencia, 'push');
            app.B_TendenciasConsumo_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_DatosClimaticos_View, true);
            app.B_TendenciasConsumo_1.Text = 'Datos climáticos';
            app.P_Graficos_Tendencia = uipanel(app.UIFigure);
            app.G_Graficos_Tendencia = uigridlayout(app.P_Graficos_Tendencia);
            app.P_Tendencias_1 = uipanel(app.G_Graficos_Tendencia);
            app.AnlisisdeDatosLabel = uilabel(app.P_Tendencias_1);
            app.AnlisisdeDatosLabel.Text = 'Análisis de Datos';
            app.P_TendenciaProduccion_1 = uipanel(app.P_Tendencias_1);
            app.G_PvsT_Tendencias_1 = uiaxes(app.P_TendenciaProduccion_1);
            title(app.G_PvsT_Tendencias_1, 'Energía producción vs tiempo')
            xlabel(app.G_PvsT_Tendencias_1, 'X')
            ylabel(app.G_PvsT_Tendencias_1, 'Y')
            zlabel(app.G_PvsT_Tendencias_1, 'Z')
            app.TA_DescripcionAnalisisPvsT_Tendencias_1 = uitextarea(app.P_TendenciaProduccion_1);
            app.P_TendenciaAnalisisRadTemp_1 = uipanel(app.P_Tendencias_1);
            app.G_RadTemp_Tendencias_1 = uiaxes(app.P_TendenciaAnalisisRadTemp_1);
            title(app.G_RadTemp_Tendencias_1, 'LALAL')
            xlabel(app.G_RadTemp_Tendencias_1, 'X')
            ylabel(app.G_RadTemp_Tendencias_1, 'Y')
            zlabel(app.G_RadTemp_Tendencias_1, 'Z')
            app.G_RadTemp_Tendencias_2 = uiaxes(app.P_TendenciaAnalisisRadTemp_1);
            title(app.G_RadTemp_Tendencias_2, 'LALAL')
            xlabel(app.G_RadTemp_Tendencias_2, 'X')
            ylabel(app.G_RadTemp_Tendencias_2, 'Y')
            zlabel(app.G_RadTemp_Tendencias_2, 'Z')
            app.G_RadTemp_Tendencias_2.Visible = 'off';
            app.TA_DescripcionAnalisisRadTemp_Tendencias_1 = uitextarea(app.P_TendenciaAnalisisRadTemp_1);
            app.B_Tendencia_CambiarGraf_1 = uibutton(app.P_TendenciaAnalisisRadTemp_1, 'state');
            app.B_Tendencia_CambiarGraf_1.ValueChangedFcn = createCallbackFcn(app, @B_Tendencia_CambiarGraf_1ValueChanged, true);
            app.B_Tendencia_CambiarGraf_1.Text = '';
            app.P_TendenciaCostoMeses_1 = uipanel(app.P_Tendencias_1);
            app.G_CostoMeses_Tendencias_1 = uiaxes(app.P_TendenciaCostoMeses_1);
            title(app.G_CostoMeses_Tendencias_1, 'ANALISIS DEL COSTO KWH ')
            xlabel(app.G_CostoMeses_Tendencias_1, 'X')
            ylabel(app.G_CostoMeses_Tendencias_1, 'Y')
            zlabel(app.G_CostoMeses_Tendencias_1, 'Z')
            app.TA_DescripcionAnalisisCostoMeses_Tendencias_1 = uitextarea(app.P_TendenciaCostoMeses_1);
            app.P_Tendencias_Vinculos = uipanel(app.P_Tendencias_1);
            app.GridLayout = uigridlayout(app.P_Tendencias_Vinculos);
            app.B_AnadirE_Tendencias_1 = uibutton(app.GridLayout, 'push');
            app.B_AnadirE_Tendencias_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_AnadirE_View, true);
            app.B_AnadirE_Tendencias_1.Text = 'Añadir electrodoméstico';
            app.B_AnadirRec_Tendencias_1 = uibutton(app.GridLayout, 'push');
            app.B_AnadirRec_Tendencias_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_AnadirRec_View, true);
            app.B_AnadirRec_Tendencias_1.Text = 'Añadir recibo';
            app.B_EditarRec_Tendencias_1 = uibutton(app.GridLayout, 'push');
            app.B_EditarRec_Tendencias_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_ModificarRec_View, true);
            app.B_EditarRec_Tendencias_1.Text = 'Editar recibos';
            app.B_EditarE_Tendencia_1 = uibutton(app.GridLayout, 'push');
            app.B_EditarE_Tendencia_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_ModificarE_View, true);
            app.B_EditarE_Tendencia_1.Text = 'Editar electrodoméstico';
            app.Panel = uipanel(app.UIFigure);
            app.Container_Botones = uigridlayout(app.Panel);
            app.B_CalculadoraConsumo_1 = uibutton(app.Container_Botones, 'push');
            app.B_CalculadoraConsumo_1.ButtonPushedFcn = createCallbackFcn(app, @CalculadoraConsumoView_1, true);
            app.B_CalculadoraConsumo_1.Text = 'Calculadora de Consumo';
            app.B_Instalacion_2 = uibutton(app.Container_Botones, 'push');
            app.B_Instalacion_2.Text = 'Instalación';
            app.B_EnergiaProducida_3 = uibutton(app.Container_Botones, 'push');
            app.B_EnergiaProducida_3.Text = 'Energía Producida';
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel = uilabel(app.UIFigure);
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel.Text = 'Calculadora de ROI en la implementación de energía solar vs eléctrica';
            app.P_inicio_1 = uipanel(app.UIFigure);
            app.P_inicio_1.Visible = 'off';
            app.B_Ingresar_Recibos_1 = uibutton(app.P_inicio_1, 'push');
            app.B_Ingresar_Recibos_1.Text = 'Ingresar recibos';
            app.B_Ingresar_Electrodomestcos_1 = uibutton(app.P_inicio_1, 'push');
            app.B_Ingresar_Electrodomestcos_1.Text = 'Ingresar electrodomésticos';
            app.B_Tendencias_1 = uibutton(app.P_inicio_1, 'push');
            app.B_Tendencias_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_View, true);
            app.B_Tendencias_1.Text = 'Tendencias';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Principal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end